classdef AxonSkewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TotalorthogonalEditField       matlab.ui.control.NumericEditField
        TotalorthogonalEditFieldLabel  matlab.ui.control.Label
        DeselectButton                 matlab.ui.control.Button
        OutputstatusTextArea           matlab.ui.control.TextArea
        OutputstatusTextAreaLabel      matlab.ui.control.Label
        ConfirmselectionButton         matlab.ui.control.Button
        DisplayonskeletonButton        matlab.ui.control.Button
        SaveoutlineButton              matlab.ui.control.Button
        DrawoutlineButton              matlab.ui.control.Button
        DeleteoutlineButton            matlab.ui.control.Button
        ImagepathTextArea              matlab.ui.control.TextArea
        ImagepathTextAreaLabel         matlab.ui.control.Label
        LoadskeletonButton             matlab.ui.control.Button
        LoadrawButton                  matlab.ui.control.Button
        UIAxes1                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        clickedPts 
        drawnLineHandles
        markerHandles
        orthoLineHandles
        segmentLengths
        segmentWeights
        totalOrthL
        orthoAll
        img1Path
        isDrawing = false
        selectedOrthoIdx = []
    end
    
    methods (Access = private)
        
        function onMouseClick(app, src, evt) %function dealing with drawing SC border
            if ~app.isDrawing
                return
            end

            clickedObj = src.CurrentObject;   % could be the axes, an image primitive, a button, etc.
            ax = [];
            if ~isempty(clickedObj) && isgraphics(clickedObj)
                ax = ancestor(clickedObj, 'matlab.graphics.axis.Axes');
            end

            % If the click was not inside our UIAxes1, ignore it
            if isempty(ax) || ax ~= app.UIAxes1
                return;
            end

            cp =ax.CurrentPoint;
            x = cp(1,1);
            y = cp(1,2);

            app.clickedPts(end+1,:) = [x y];
            g = plot(app.UIAxes1, x, y, 'ro', 'MarkerSize',6);
            app.markerHandles(end+1) = g;
            if size(app.clickedPts,1) > 1
                p1 = app.clickedPts(end-1,:);
                p2 = app.clickedPts(end,:);

                h = line(app.UIAxes1, ...
                    [p1(1) p2(1)], [p1(2) p2(2)], ...
                    'Color','r','LineWidth',2);
                app.drawnLineHandles(end+1) = h;
            end
        end
        
        function [tl, segl] = border_seg_length(app)
            ptn = numel(app.clickedPts)/2;
            tl = 0;
            segl = zeros([numel(ptn)-1, 1]);
            for i =2:numel(ptn)
                v = app.clickedPts(i)- app.clickedPts(i-1);
                %l = l + norm(v);
                segl(i) = norm(v);
                tl = tl+segl(i);
            end
        end

        function calculate_orthognals(app)
            app.orthoAll = cell([numel(app.clickedPts)/2-1, 1]);
            for i = 1:numel(app.orthoAll)
                
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.clickedPts = [];
            app.drawnLineHandles = gobjects(0);
            app.orthoLineHandles = gobjects(0);
        end

        % Button pushed function: LoadrawButton
        function LoadrawButtonPushed(app, event)
            [f, p] = uigetfile('*.tif');
            if isequal(f,0); return; end

            app.img1Path = fullfile(p,f);
            imbuffer = imread(app.img1Path);
            img1_uint8 = imbuffer;
            %img1_uint8 =im2uint8(mat2gray(imbuffer));
            if ndims(img1_uint8) == 2
                img1_uint8 = repmat(img1_uint8, 1, 1, 3);
            end
            %app.Image1.ImageSource = img1_uint8;
            imshow(img1_uint8, 'Parent', app.UIAxes1);
            app.ImagepathTextArea.Value = app.img1Path;
            hImg = findobj(app.UIAxes1, 'Type', 'Image');
            set(hImg, 'HitTest', 'off', 'PickableParts', 'none');

            %display in axis as well?
            cla(app.UIAxes1);                       % clear previous contents
            image(app.UIAxes1, img1_uint8);         % show RGB uint8 image
            axis(app.UIAxes1, 'image');             % preserve aspect ratio / pixel aspect
            app.UIAxes1.XTick = [];                 % hide tick marks
            app.UIAxes1.YTick = [];
            app.UIAxes1.YDir = 'reverse';           % make (x,y) clicks intuitive (row increases downward)
            hold(app.UIAxes1, 'on');                % allow overlaying lines/markers

            try
                app.UIAxes1.Color = 'none';
                uistack(app.UIAxes1, 'top');
            catch
                % older versions / layout may not support uistack/layout change — ignore if fails
            end
        end

        % Button pushed function: LoadskeletonButton
        function LoadskeletonButtonPushed(app, event)
            [f, p] = uigetfile('*.tif');
            if isequal(f,0); return; end

            im2path = fullfile(p,f);
            imbuffer = imread(im2path);
            im2_uint8 = imbuffer;
            %img2_uint8 =im2uint8(mat2gray(imbuffer));
            if ndims(im2_uint8) == 2
                img2_uint8 = repmat(im2_uint8, 1, 1, 3);
            end
            imshow(im2_uint8, 'Parent', app.UIAxes2);
            hImg = findobj(app.UIAxes2, 'Type', 'Image');
            set(hImg, 'HitTest', 'off', 'PickableParts', 'none');
            cla(app.UIAxes2);                       % clear previous contents
            image(app.UIAxes2, img1_uint8);         % show RGB uint8 image
            axis(app.UIAxes2, 'image');             % preserve aspect ratio / pixel aspect
            app.UIAxes2.XTick = [];                 % hide tick marks
            app.UIAxes2.YTick = [];
            app.UIAxes2.YDir = 'reverse';           % make (x,y) clicks intuitive (row increases downward)
            %hold(app.UIAxes2, 'on');          
        end

        % Button pushed function: DrawoutlineButton
        function DrawoutlineButtonPushed(app, event)
            app.isDrawing = true;
            app.clickedPts = [];
            % Clear existing graphics safely
            if ~isempty(app.drawnLineHandles)
                delete(app.drawnLineHandles);
            end
            if ~isempty(app.markerHandles)
                delete(app.markerHandles);
            end

             app.markerHandles = gobjects(0);
             app.drawnLineHandles = gobjects(0);
             app.UIFigure.WindowButtonDownFcn = @(src,evt) onMouseClick(app, src, evt);

             % Make sure axis is on top and ready for overlays
             try
                 app.UIAxes1.Color = 'none';
                 uistack(app.UIAxes1, 'top');
             catch
             end
             hold(app.UIAxes1, 'on');
        end

        % Button pushed function: SaveoutlineButton
        function SaveoutlineButtonPushed(app, event)
            app.isDrawing = false;
            app.UIFigure.WindowButtonDownFcn = [];
            calculate_orthognals(app);
        end

        % Value changed function: TotalorthogonalEditField
        function TotalorthogonalEditFieldValueChanged(app, event)
            comp = app.TotalorthogonalEditField.Value;
            val = readNumberFromEditField(app, comp);
            if isnumeric(val) && isscalar(val) && ~isnan(val)
                app.totalOrthL = val;
            else
                if ischar(val) || isstring(val)
                    val = str2double(val);
                    if ~isnan(val)
                        app.totalOrthL = val;
                    end
                end
            end
        end

        % Button pushed function: DeleteoutlineButton
        function DeleteoutlineButtonPushed(app, event)
            app.clickedPts = [];
            % Clear existing graphics
            if ~isempty(app.drawnLineHandles)
                delete(app.drawnLineHandles);
            end
            if ~isempty(app.markerHandles)
                delete(app.markerHandles);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Axon mask')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'Image')
            app.UIAxes1.XTick = [];
            app.UIAxes1.YTick = [];
            app.LoadrawButton = uibutton(app.UIFigure, 'push');
            app.LoadrawButton.ButtonPushedFcn = createCallbackFcn(app, @LoadrawButtonPushed, true);
            app.LoadrawButton.Text = 'Load raw';
            app.LoadskeletonButton = uibutton(app.UIFigure, 'push');
            app.LoadskeletonButton.ButtonPushedFcn = createCallbackFcn(app, @LoadskeletonButtonPushed, true);
            app.LoadskeletonButton.Text = 'Load skeleton';
            app.ImagepathTextAreaLabel = uilabel(app.UIFigure);
            app.ImagepathTextAreaLabel.Text = 'Image path';
            app.ImagepathTextArea = uitextarea(app.UIFigure);
            app.DeleteoutlineButton = uibutton(app.UIFigure, 'push');
            app.DeleteoutlineButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteoutlineButtonPushed, true);
            app.DeleteoutlineButton.Text = 'Delete outline';
            app.DrawoutlineButton = uibutton(app.UIFigure, 'push');
            app.DrawoutlineButton.ButtonPushedFcn = createCallbackFcn(app, @DrawoutlineButtonPushed, true);
            app.DrawoutlineButton.Text = 'Draw outline';
            app.SaveoutlineButton = uibutton(app.UIFigure, 'push');
            app.SaveoutlineButton.ButtonPushedFcn = createCallbackFcn(app, @SaveoutlineButtonPushed, true);
            app.SaveoutlineButton.Text = 'Save outline';
            app.DisplayonskeletonButton = uibutton(app.UIFigure, 'push');
            app.DisplayonskeletonButton.Text = 'Display on skeleton';
            app.ConfirmselectionButton = uibutton(app.UIFigure, 'push');
            app.ConfirmselectionButton.Text = 'Confirm selection';
            app.OutputstatusTextAreaLabel = uilabel(app.UIFigure);
            app.OutputstatusTextAreaLabel.Text = 'Output status';
            app.OutputstatusTextArea = uitextarea(app.UIFigure);
            app.DeselectButton = uibutton(app.UIFigure, 'push');
            app.DeselectButton.Text = 'Deselect';
            app.TotalorthogonalEditFieldLabel = uilabel(app.UIFigure);
            app.TotalorthogonalEditFieldLabel.Text = 'Total orthogonal';
            app.TotalorthogonalEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalorthogonalEditField.ValueChangedFcn = createCallbackFcn(app, @TotalorthogonalEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AxonSkewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end